classdef aplikacja_glowna < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SymulacjaEpidemii              matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        WochyTab                       matlab.ui.container.Tab
        ITAxes                         matlab.ui.control.UIAxes
        ITSymulacjaepidemiidlaWochLabel  matlab.ui.control.Label
        ITWczytanieprzypadkupocztkowegoLabel  matlab.ui.control.Label
        ITLoadButton                   matlab.ui.control.Button
        ITWynikiLabel                  matlab.ui.control.Label
        ITRecoveredTextAreaLabel       matlab.ui.control.Label
        ITRecoveredTextArea            matlab.ui.control.TextArea
        ITDeadTextAreaLabel            matlab.ui.control.Label
        ITDeadTextArea                 matlab.ui.control.TextArea
        Dziepocztkowyepidemiiodpocztkowejdatyzbieraniadanych2802Label  matlab.ui.control.Label
        ITStartdayEditField            matlab.ui.control.NumericEditField
        LiczbaiteracjidnisymulowanychEditFieldLabel  matlab.ui.control.Label
        ITLiczbaiteracjidnisymulowanychEditField  matlab.ui.control.NumericEditField
        ITProszwczytasterowaniaprawdopodobiestwaLabel  matlab.ui.control.Label
        ITDzienSymulacjiTextAreaLabel  matlab.ui.control.Label
        ITDzienSymulacjiTextArea       matlab.ui.control.TextArea
        ITPrawdopodobienstwowystapieniaobjawowTextAreaLabel  matlab.ui.control.Label
        ITPrawdopodobienstwowykonaniatestuTextAreaLabel  matlab.ui.control.Label
        ITNapis1TextAreaLabel          matlab.ui.control.Label
        ITNapis2                       matlab.ui.control.Label
        ITPrawdopodobienstwowykonaniatestuEditField  matlab.ui.control.EditField
        ITPrawdopodobienstwowystapieniaobjawowEditField  matlab.ui.control.EditField
        ITIlosctestowTextAreaLabel     matlab.ui.control.Label
        ITIlosctestowEditField         matlab.ui.control.EditField
        ITProszwczytasterowaniatestyLabel  matlab.ui.control.Label
        ITSterowaniaLabel              matlab.ui.control.Label
        HiszpaniaTab                   matlab.ui.container.Tab
        pq_iEditFieldLabel             matlab.ui.control.Label
        pq_iEditField                  matlab.ui.control.EditField
        pq_isEditFieldLabel            matlab.ui.control.Label
        pq_isEditField                 matlab.ui.control.EditField
        pi_isEditFieldLabel            matlab.ui.control.Label
        pi_isEditField                 matlab.ui.control.EditField
        pi_rEditFieldLabel             matlab.ui.control.Label
        pi_rEditField                  matlab.ui.control.EditField
        ps_ihEditFieldLabel            matlab.ui.control.Label
        ps_ihEditField                 matlab.ui.control.EditField
        pis_ihEditFieldLabel           matlab.ui.control.Label
        pis_ihEditField                matlab.ui.control.EditField
        pis_rEditFieldLabel            matlab.ui.control.Label
        pis_rEditField                 matlab.ui.control.EditField
        pih_rEditFieldLabel            matlab.ui.control.Label
        pih_rEditField                 matlab.ui.control.EditField
        pih_dEditFieldLabel            matlab.ui.control.Label
        pih_dEditField                 matlab.ui.control.EditField
        pih_hEditFieldLabel            matlab.ui.control.Label
        pih_hEditField                 matlab.ui.control.EditField
        ogr_sumEditFieldLabel          matlab.ui.control.Label
        ogr_sumEditField               matlab.ui.control.EditField
        rozmiarEditFieldLabel          matlab.ui.control.Label
        rozmiarEditField               matlab.ui.control.EditField
        STARTButton                    matlab.ui.control.Button
        wstepLabel                     matlab.ui.control.Label
        InformacjezwrotneLabel         matlab.ui.control.Label
        plot_gl                        matlab.ui.control.UIAxes
        tabela_disp                    matlab.ui.control.Table
        wyniki_w_licz                  matlab.ui.control.Label
        Ozn_stanow                     matlab.ui.control.Label
        Ozn_stanow_tytul               matlab.ui.control.Label
        DodatkowewykresyButton         matlab.ui.control.Button
        sym_werLabel                   matlab.ui.control.Label
        sym_werDropDown                matlab.ui.control.DropDown
        AustriaTab                     matlab.ui.container.Tab
        AustriaStart                   matlab.ui.control.Button
        no_security_measuresEditFieldLabel  matlab.ui.control.Label
        no_security_measuresEditField  matlab.ui.control.EditField
        self_protectingEditFieldLabel  matlab.ui.control.Label
        self_protectingEditField       matlab.ui.control.EditField
        protecting_othersEditFieldLabel  matlab.ui.control.Label
        protecting_othersEditField     matlab.ui.control.EditField
        neighbours_thresholdEditFieldLabel  matlab.ui.control.Label
        neighbours_thresholdEditField  matlab.ui.control.EditField
        miracleEditFieldLabel          matlab.ui.control.Label
        miracleEditField               matlab.ui.control.EditField
        death_rateEditFieldLabel       matlab.ui.control.Label
        death_rateEditField            matlab.ui.control.EditField
        self_protecting_thresholdEditFieldLabel  matlab.ui.control.Label
        self_protecting_thresholdEditField  matlab.ui.control.EditField
        protecting_others_thresholdEditFieldLabel  matlab.ui.control.Label
        protecting_others_thresholdEditField  matlab.ui.control.EditField
        maxiterEditFieldLabel          matlab.ui.control.Label
        maxiterEditField               matlab.ui.control.EditField
        AustriaVis                     matlab.ui.control.UIAxes
        AustriaTable                   matlab.ui.control.Table
        SymulacjaepidemiidlaAustriiLabel  matlab.ui.control.Label
        WmodelukomrkamoeosigajesenzsiedmiustanwLabel  matlab.ui.control.Label
        AustraliaTab                   matlab.ui.container.Tab
        Title                          matlab.ui.control.Label
        Text1                          matlab.ui.control.Label
        StartAustralia                 matlab.ui.control.Button
        Ograniczenia010SpinnerLabel_2  matlab.ui.control.Label
        Ograniczenia                   matlab.ui.control.Spinner
        Testy010SpinnerLabel_2         matlab.ui.control.Label
        Testy                          matlab.ui.control.Spinner
        Politykainformacyjna110SpinnerLabel_2  matlab.ui.control.Label
        Politykainformacyjna           matlab.ui.control.Spinner
        MaxiteracjiEditFieldLabel_2    matlab.ui.control.Label
        Maxiteracji                    matlab.ui.control.NumericEditField
        WykresAustralia                matlab.ui.control.UIAxes
        TableAustralia                 matlab.ui.control.Table
        Text1_4                        matlab.ui.control.Label
        Text1_5                        matlab.ui.control.Label
        Text1_6                        matlab.ui.control.Label
        Text1_7                        matlab.ui.control.Label
        SEIRTab                        matlab.ui.container.Tab
        Text1_8                        matlab.ui.control.Label
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        cEditFieldLabel                matlab.ui.control.Label
        cEditField                     matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        dEditField                     matlab.ui.control.NumericEditField
        eEditFieldLabel                matlab.ui.control.Label
        eEditField                     matlab.ui.control.NumericEditField
        fEditFieldLabel                matlab.ui.control.Label
        fEditField                     matlab.ui.control.NumericEditField
        gEditFieldLabel                matlab.ui.control.Label
        gEditField                     matlab.ui.control.NumericEditField
        hEditFieldLabel                matlab.ui.control.Label
        hEditField                     matlab.ui.control.NumericEditField
        iEditFieldLabel                matlab.ui.control.Label
        iEditField                     matlab.ui.control.NumericEditField
        jEditFieldLabel                matlab.ui.control.Label
        jEditField                     matlab.ui.control.NumericEditField
        kEditFieldLabel                matlab.ui.control.Label
        kEditField                     matlab.ui.control.NumericEditField
        lEditFieldLabel                matlab.ui.control.Label
        lEditField                     matlab.ui.control.NumericEditField
        mEditFieldLabel                matlab.ui.control.Label
        mEditField                     matlab.ui.control.NumericEditField
        nEditFieldLabel                matlab.ui.control.Label
        nEditField                     matlab.ui.control.NumericEditField
        STARTButton_SEIR               matlab.ui.control.Button
        DeadPlot                       matlab.ui.control.UIAxes
        Text1_9                        matlab.ui.control.Label
        CasesPlot                      matlab.ui.control.UIAxes
        SterowanieuEditFieldLabel      matlab.ui.control.Label
        uEditField                     matlab.ui.control.NumericEditField
        DugosymulacjiEditFieldLabel    matlab.ui.control.Label
        DugosymulacjiEditField         matlab.ui.control.NumericEditField
        SEIRTable                      matlab.ui.control.Table
        SymulacjaepidemiidlaAustriiLabel_2  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function plot_glowny(app)
            calosc_app = load('Hiszpania\calosc.mat');
            
            app.plot_gl.cla;
            for n = 1:calosc_app.liczba_cykli
                imshow(calosc_app.plansza_pom(:,:,n),'Parent',app.plot_gl,'DisplayRange',[0.1 7.5],'Colormap',jet);
                
                % colorbar
                c = colorbar(app.plot_gl);
                c.Label.String = 'Stany Q2.Q1';
                c.Ticks = [0.1 0.3 1.5 2.1 2.2 2.4 3.1 3.3 3.4 4.1 4.2 4.4 5.5 6.1 6.3 7.5];
                axis(app.plot_gl,[1 calosc_app.rozmiar 1 calosc_app.rozmiar]);
                daspect(app.plot_gl,[1,1,1])
                % napisz, ktory to cykl:
                text(app.plot_gl,2,2.5,['cykl ' num2str(n)],'Color','white');
                pause(0.05);
            end
        end
        
        function tabela(app)
            calosc_app = load('Hiszpania\calosc.mat');
            app.tabela_disp.Data = {'pierwszy',calosc_app.healthy_przypadki*calosc_app.skala,calosc_app.liczba_wykrytych_zakazen_total(calosc_app.dzien0),calosc_app.liczba_wyleczen_total(calosc_app.dzien0),calosc_app.przypadki_smiertelne_total(calosc_app.dzien0);
                'ostatni',calosc_app.h_len_fin(end)*calosc_app.skala,calosc_app.z_len_fin(end)*calosc_app.skala,calosc_app.w_len_fin(end)*calosc_app.skala,calosc_app.o_len_fin(end)*calosc_app.skala};
        end
        
        function update_plot_IT(app,world,num,dzielnik)
            rozmiar=500;
            world_day_cnt = zeros(rozmiar,rozmiar);
            for n=1:app.ITLiczbaiteracjidnisymulowanychEditField.Value
                
                % i tylko je wyswietlamy:
                % najpierw J - kolumny, potem 50-I - wiersze do gory nogami
                % (bo y na wykresie rosnie w gore, a numery wierszy macierzy rosna w dol)
                [I1,J1] = find(world==1.1 | world==1.2 | world==1.4);
                [I2,J2] = find(world==6.1 | world==6.3);
                [I3,J3] = find(world==7);
                
                
                app.ITAxes.cla;
                p1=plot(app.ITAxes,J1,rozmiar-I1,'.','Marker', 's', 'MarkerFaceColor', 'r', 'MarkerSize', 4);
                hold(app.ITAxes,'on');
                p2=plot(app.ITAxes,J2,rozmiar-I2,'.','Marker', 's', 'MarkerFaceColor', 'g', 'MarkerSize', 4);
                p3=plot(app.ITAxes,J3,rozmiar-I3,'.','Marker', 's', 'MarkerFaceColor', 'black', 'MarkerSize', 4);
                axis([1 rozmiar 1 rozmiar]);
                daspect([1,1,1])
                % napisz, ktory to cykl:
                title(app.ITAxes,'Wynik symulacji');
                legend(app.ITAxes,'Liczba zachorowań', 'Liczba wyzdrowień', 'Liczba zmarłych');
                text(app.ITAxes,2,2.5,['cykl ' num2str(n)]);
                drawnow;
                
                %--- KROK 2 ----------------------------------------------------
                % oblicz stany komorek do nastepnego cyklu:
                
                worldPom = zeros(rozmiar,rozmiar);
                world_day_cntPom = zeros(rozmiar,rozmiar);
                
                %Prawdopodobieństwa
                no_security = get_no_security_m(num,app.ITStartdayEditField.Value);
                H_to_X_NOSECURITY = [2.5, 8, 0.1];
                P_H_NOSECURITY_NEI_NOSECURITY = [0.7, 0.01, 0.29];    %prawd. wykrycia kontaktu
                P_H_NOSECURITY_NEI_INFECTING = [0.7, 0.015, 0.285];
                P_H_NOSECURITY_NEI_VIRUS_SELF = [0, 0.015, 0.985];
                H_to_X_SELF = [2.5, 8, 0.3];
                P_H_SELF_NEI_NOSECURITY = [0.7, 0.005, 0.295];
                P_H_SELF_NEI_INFECTING = [0.7, 0.01, 0.29];
                P_H_SELF_NEI_VIRUS_SELF = [0, 0.005 0.995];
                H_to_S_NOSECURITY = [3.1 0.1];
                P_H_to_S_NOSECURITY = [0.015 0.985];    %prawd. zarażenia sie inna chorob
                P_H_to_S_NOSECURITY_more_2 = [0.03 0.97];
                H_to_S_SELF = [3.3 0.3];
                P_H_to_S_SELF = [0.01 0.98];
                P_H_to_S_SELF_more_2 = [0.02 0.97];
                %P_I_IN_AND_SICK = 0.3;
                P_I_IN_AND_SICK = app.ITPrawdopodobienstwowystapieniaobjawowEditField.Value;
                %P_Q_TEST_PROB = 0.8;
                P_Q_TEST_PROB = app.ITPrawdopodobienstwowykonaniatestuEditField.Value;
                Q_to_X = [1, 2.5];
                if no_security <0.6
                    P_Q_to_X = [0.015, 0.985]; %prawd. ze wystapilo zachorowanie
                elseif no_security >= 0.6 && no_security <= 0.8
                    P_Q_to_X = [0.02, 0.98];
                else
                    P_Q_to_X = [0.03, 0.97];
                end
                S_to_X_SELF = [2.5, 3.3];
                P_S_SELF = [0.6, 0.4];
                IN_A_SICK_TO_X = [4.1, 4.2, 4.4, 5.5, 6, 7];
                P_IN_A_SICK_4_1 = [0.842 0 0 0.1 0.05, 0.008];
                P_IN_A_SICK_4_2 = [0 0.842 0 0.1 0.05, 0.008];
                P_IN_A_SICK_4_4 = [0 0 0.842 0.1 0.05, 0.008];
                HOSPITAL_TO_X = [5.5, 6, 7];
                P_HOSPITAL = [0.947, 0.042, 0.011];


                % dla kazdej z komorek planszy zycie obliczamy, ile maja sasiadow
                % i w zaleznosci od ich liczby wstawiamy do zyciePom jedynke lub nic
                % nie wstawiamy
                for i=1:rozmiar
                    for j=1:rozmiar
                        komorka = world(i,j);
                        security = get_no_security_m(num,app.ITStartdayEditField.Value);
                        
                        sasiedzi_1=fPoliczSasiadow(world,i,j,1.1);
                        sasiedzi_2=fPoliczSasiadow(world,i,j,1.2);
                        sasiedzi_3=fPoliczSasiadow(world,i,j,1.4);
                        sasiedzi_4=fPoliczSasiadow(world,i,j,4.1);
                        sasiedzi_5=fPoliczSasiadow(world,i,j,4.2);
                        sasiedzi_6=fPoliczSasiadow(world,i,j,4.4);
                        sasiedzi_7=fPoliczSasiadow(world,i,j,3.1);
                        sasiedzi_8=fPoliczSasiadow(world,i,j,3.3);
                        sasiedzi_9=fPoliczSasiadow(world,i,j,8.1);
                        sasiedzi_10=fPoliczSasiadow(world,i,j,8.2);
                        sasiedzi_11=fPoliczSasiadow(world,i,j,8.3);
                        
                        switch komorka
                            
                            case 0.1
                                
                                %zmiana na IN QUARANTINE
                                if sasiedzi_9 > 2 || sasiedzi_10 > 2 || sasiedzi_11 > 2
                                    worldPom(i,j)=8;
                                elseif(sasiedzi_1 >= 1 && sasiedzi_1 <= 2) || (sasiedzi_4 >= 1 && sasiedzi_4 <= 2) || (sasiedzi_9 >= 1 && sasiedzi_9 <= 2)
                                    worldPom(i,j)=count_prob(H_to_X_NOSECURITY,P_H_NOSECURITY_NEI_NOSECURITY);
                                elseif(sasiedzi_2 >= 1 && sasiedzi_2 <= 2) || (sasiedzi_5 >= 1 && sasiedzi_5 <= 2) || (sasiedzi_10 >= 1 && sasiedzi_10 <= 2)
                                    worldPom(i,j)=count_prob(H_to_X_NOSECURITY,P_H_NOSECURITY_NEI_INFECTING);
                                elseif(sasiedzi_11 >= 1 && sasiedzi_11 <= 2)
                                    worldPom(i,j)=count_prob(H_to_X_NOSECURITY,P_H_NOSECURITY_NEI_VIRUS_SELF);
                                elseif sasiedzi_1 > 2 || sasiedzi_4 > 2
                                    worldPom(i,j)=2.5;
                                elseif sasiedzi_2 > 2 || sasiedzi_5 > 2
                                    worldPom(i,j)=2.5;
                                elseif(sasiedzi_3 >= 1) || (sasiedzi_6 >= 1)
                                    worldPom(i,j)=2.5;
                                    
                                    %zmiana na SICK
                                    
                                elseif(sasiedzi_7 >= 1 && sasiedzi_7 <= 2) || (sasiedzi_8 >= 1 && sasiedzi_8 <= 2)
                                    worldPom(i,j)=count_prob(H_to_S_NOSECURITY,P_H_to_S_NOSECURITY);
                                elseif sasiedzi_7 > 2 || sasiedzi_8 > 2
                                    worldPom(i,j)=count_prob(H_to_S_NOSECURITY,P_H_to_S_NOSECURITY_more_2);
                                else
                                    worldPom(i,j)=world(i,j);
                                end
                                
                                if worldPom(i,j)==8
                                    P=[security, security, 1-2*security];
                                    W=[8.1, 8.2, 8.3];
                                    worldPom(i,j)=count_prob(W,P);
                                end
                                
                                
                            case 0.3
                                
                                %zmiana na IN QUARANTINE
                                if sasiedzi_9 > 2 || sasiedzi_10 > 2 || sasiedzi_11 > 2
                                    worldPom(i,j)=8;
                                elseif(sasiedzi_11 >= 1 && sasiedzi_11 <= 2)
                                    worldPom(i,j)=count_prob(H_to_X_NOSECURITY,P_H_SELF_NEI_VIRUS_SELF);
                                elseif(sasiedzi_1 >= 1 && sasiedzi_1 <= 2) || (sasiedzi_4 >= 1 && sasiedzi_4 <= 2) || (sasiedzi_9 >= 1 && sasiedzi_9 <= 2)
                                    worldPom(i,j)=count_prob(H_to_X_SELF,P_H_SELF_NEI_NOSECURITY);
                                elseif(sasiedzi_2 >= 1 && sasiedzi_2 <= 2) || (sasiedzi_5 >= 1 && sasiedzi_5 <= 2) || (sasiedzi_10 >= 1 && sasiedzi_10 <= 2)
                                    worldPom(i,j)=count_prob(H_to_X_SELF,P_H_SELF_NEI_INFECTING);
                                elseif sasiedzi_1 > 2 || sasiedzi_4 > 2
                                    worldPom(i,j)=2.5;
                                elseif sasiedzi_2 > 2 || sasiedzi_5 > 2
                                    worldPom(i,j)=2.5;
                                elseif(sasiedzi_3 >= 1) || (sasiedzi_6 >= 1)
                                    worldPom(i,j)=2.5;
                                    
                                    %zmiana na SICK
                                    
                                elseif(sasiedzi_7 >= 1 && sasiedzi_7 <= 2) || (sasiedzi_8 >= 1 && sasiedzi_8 <= 2)
                                    worldPom(i,j)=count_prob(H_to_S_SELF,P_H_to_S_SELF);
                                elseif sasiedzi_7 > 2 || sasiedzi_8 > 2
                                    worldPom(i,j)=count_prob(H_to_S_SELF,P_H_to_S_SELF_more_2);
                                else
                                    worldPom(i,j)=world(i,j);
                                end
                                
                                if worldPom(i,j)==8
                                    P=[security, security, 1-2*security];
                                    W=[8.1, 8.2, 8.3];
                                    worldPom(i,j)=count_prob(W,P);
                                end
                                
                            case {1.1, 1.2, 1.4}
                                if world_day_cnt(i,j) == 14      %ilosc dni to wystapienia objawow
                                    P=[security,1-security];
                                    W=[6.1, 6.3];
                                    worldPom(i,j)=count_prob(W,P);
                                    world_day_cntPom(i,j)=0;
                                else
                                    world_day_cntPom(i,j) = world_day_cnt(i,j)+1;
                                    p = randi(10);
                                    if p <= P_I_IN_AND_SICK*10
                                        switch world(i,j)
                                            case 1.1
                                                worldPom(i,j)=4.1;
                                                world_day_cntPom(i,j)=0;
                                            case 1.2
                                                worldPom(i,j)=4.2;
                                                world_day_cntPom(i,j)=0;
                                            case 1.4
                                                worldPom(i,j)=4.4;
                                                world_day_cntPom(i,j)=0;
                                        end
                                    else
                                        worldPom(i,j)=world(i,j);
                                    end
                                end
                                
                            case 2.5
                                if world_day_cnt(i,j) == 14
                                    P=[security,1-security];
                                    W=[0.1, 0.3];
                                    worldPom(i,j)=count_prob(W,P);
                                    world_day_cntPom(i,j)=0;
                                else
                                    world_day_cntPom(i,j) = world_day_cnt(i,j)+1;
                                    p = randi(10);
                                    if p <= P_Q_TEST_PROB*10
                                        worldPom(i,j)=count_prob(Q_to_X,P_Q_to_X);
                                        if worldPom(i,j)==1
                                            P=[security, security, 1-2*security];
                                            W=[1.1, 1.2, 1.4];
                                            worldPom(i,j)=count_prob(W,P);
                                            world_day_cntPom(i,j)=0;
                                        end
                                    else
                                        worldPom(i,j)=world(i,j);
                                        
                                    end
                                end
                                
                            case {3.1, 3.3}
                                if world_day_cnt(i,j) == 10
                                    switch world(i,j)
                                        case 3.1
                                            worldPom(i,j)=0.1;
                                        case 3.3
                                            worldPom(i,j)=0.3;
                                    end
                                    world_day_cntPom(i,j)=0;
                                else
                                    world_day_cntPom(i,j) = world_day_cnt(i,j)+1;
                                    if(sasiedzi_1 >= 1 && sasiedzi_1 <= 2) || (sasiedzi_4 >= 1 && sasiedzi_4 <= 2)
                                        worldPom(i,j)=count_prob(S_to_X_SELF,P_S_SELF);
                                        world_day_cntPom(i,j)=0;
                                    elseif sasiedzi_1 > 2 || sasiedzi_4 > 2
                                        worldPom(i,j)=2.5;
                                        world_day_cntPom(i,j)=0;
                                    elseif(sasiedzi_2 >= 1 && sasiedzi_2 <= 2) || (sasiedzi_5 >= 1 && sasiedzi_5 <= 2)
                                        worldPom(i,j)=count_prob(S_to_X_SELF,P_S_SELF);
                                        world_day_cntPom(i,j)=0;
                                    elseif sasiedzi_2 > 2 || sasiedzi_5 > 2
                                        worldPom(i,j)=2.5;
                                        world_day_cntPom(i,j)=0;
                                    elseif(sasiedzi_3 >= 1) || (sasiedzi_6 >= 1)
                                        worldPom(i,j)=2.5;
                                        world_day_cntPom(i,j)=0;
                                    else
                                        worldPom(i,j)=world(i,j);
                                    end
                                end
                                
                            case {4.1, 4.2, 4.4}
                                if world_day_cnt(i,j) == 14
                                    P=[security,1-security];
                                    W=[6.1, 6.3];
                                    worldPom(i,j)=count_prob(W,P);
                                    world_day_cntPom(i,j)=0;
                                else
                                    world_day_cntPom(i,j) = world_day_cnt(i,j)+1;
                                    switch world(i,j)
                                        case 4.1
                                            worldPom(i,j)=count_prob(IN_A_SICK_TO_X,P_IN_A_SICK_4_1);
                                            if world(i,j)==6
                                                P=[security, 1-security];
                                                W=[6.1, 6.3];
                                                worldPom(i,j)=count_prob(W,P);
                                                world_day_cntPom(i,j)=0;
                                            end
                                            if worldPom(i,j)~=4.1
                                                world_day_cntPom(i,j)=0;
                                            end
                                        case 4.2
                                            worldPom(i,j)=count_prob(IN_A_SICK_TO_X,P_IN_A_SICK_4_2);
                                            if world(i,j)==6
                                                P=[security, 1-security];
                                                W=[6.1, 6.3];
                                                worldPom(i,j)=count_prob(W,P);
                                                world_day_cntPom(i,j)=0;
                                            end
                                            if worldPom(i,j)~=4.2
                                                world_day_cntPom(i,j)=0;
                                            end
                                        case 4.4
                                            worldPom(i,j)=count_prob(IN_A_SICK_TO_X,P_IN_A_SICK_4_4);
                                            if world(i,j)==6
                                                P=[security, 1-security];
                                                W=[6.1, 6.3];
                                                worldPom(i,j)=count_prob(W,P);
                                                world_day_cntPom(i,j)=0;
                                            end
                                            if worldPom(i,j)~=4.4
                                                world_day_cntPom(i,j)=0;
                                            end
                                    end
                                end
                                
                            case 5.5
                                worldPom(i,j)=count_prob(HOSPITAL_TO_X,P_HOSPITAL);
                                if worldPom(i,j)==6
                                    P=[security, 1-security];
                                    W=[6.1, 6.3];
                                    worldPom(i,j)=count_prob(W,P);
                                end
                                
                                
                            case {6.1,6.3}
                                worldPom(i,j)=world(i,j);
                                
                            case 7
                                worldPom(i,j)=world(i,j);
                                
                            case {8.1, 8.2, 8.3}
                                if world_day_cnt(i,j) == 5
                                    P=[P_I_IN_AND_SICK*10,1-P_I_IN_AND_SICK*10];
                                    W=[2.5, 0];
                                    worldPom(i,j)=count_prob(W,P);
                                    if worldPom(i,j)==0
                                        P=[security, 1-security];
                                        W=[0.1, 0.3];
                                        worldPom(i,j)=count_prob(W,P);
                                    end
                                    world_day_cntPom(i,j)=0;
                                else
                                    world_day_cntPom(i,j) = world_day_cnt(i,j)+1;
                                    worldPom(i,j)=world(i,j);
                                end
                        end
                    end
                end
                
                %--- KROK 3 ----------------------------------------------------
                % Wprowadzam sterowanie, które powoduje wykonanie testow losowym
                % komórkom.
                %ilosc_testow = round(num(1,19));
                %ilosc_testow = 0;
                ilosc_testow = cellstr(app.ITIlosctestowEditField.Value);
                ilosc_testow = str2double(ilosc_testow);
                worldPom = sterowanie_test(worldPom,ilosc_testow);
               
                %--- KROK 4 ----------------------------------------------------
                % Przepisujemy wartosci z zyciePom do zycie.
                
                % Zerujemy znowu tablice.
                world = zeros(rozmiar);
                world_day_cnt = zeros(rozmiar);
                
                % Przepisujemy wartości.
                [I,J] = find(worldPom);
                liczbaNieZer = length(I);
                [X,Y] = find(world_day_cntPom);
                liczbaNieZer1 = length(X);
                
                for m=1:liczbaNieZer
                    world(I(m),J(m)) = worldPom(I(m),J(m));
                end
                for m=1:liczbaNieZer1
                    world_day_cnt(X(m),Y(m)) = world_day_cntPom(X(m),Y(m));
                end
                
                app.ITDzienSymulacjiTextArea.Value = num2str(n);
                app.ITRecoveredTextArea.Value = num2str(round(dzielnik*(sum((world(:)==6.1))+sum((world(:)==6.3)))));
                app.ITDeadTextArea.Value = num2str(round(dzielnik*sum((world(:)==7))));
                
            end
        end
        
        function [neighbors_sum, max_sum] = NeighboursSum(app, q1,q2,x,y)
    sum = 0;
    max_sum_temp = 0;
    no_security_measures = 1;
    self_protecting = 0.7;
    protecting_others = 0.3;
    
    [w,k] = size(q1);
    
    if x>1 
        if q2(x-1,y)==0 %q2 no security measures
            sum = sum+no_security_measures*q1(x-1,y);
        elseif q2(x-1,y)==1 %q2 self protecting
            sum = sum+self_protecting*q1(x-1,y);
        elseif q2(x-1,y)==2 %q2 protecting others
            sum = sum+protecting_others*q1(x-1,y);
        end
        max_sum_temp = max_sum_temp+1;
    end
    if y>1 
        if q2(x,y-1)==0
            sum = sum+no_security_measures*q1(x,y-1);
        elseif q2(x,y-1)==1
            sum = sum+self_protecting*q1(x,y-1);
        elseif q2(x,y-1)==2
            sum = sum+protecting_others*q1(x,y-1);
        end
        max_sum_temp = max_sum_temp+1;
    end
    if x<w  
        if q2(x+1,y)==0
            sum = sum+no_security_measures*q1(x+1,y);
        elseif q2(x+1,y)==1
            sum = sum+self_protecting*q1(x+1,y);
        elseif q2(x+1,y)==2
            sum = sum+protecting_others*q1(x+1,y);    
        end
        max_sum_temp = max_sum_temp+1;
    end
    if y<k 
        if q2(x,y+1)==0
            sum = sum+no_security_measures*q1(x,y+1);
        elseif q2(x,y+1)==1
            sum = sum+self_protecting*q1(x,y+1);
        elseif q2(x,y+1)==2
            sum = sum+protecting_others*q1(x,y+1);   
        end
        max_sum_temp = max_sum_temp+1;
    end
    if x>1 && y>1 
        if q2(x-1,y-1)==0
            sum = sum+no_security_measures*q1(x-1,y-1);
        elseif q2(x-1,y-1)==1
            sum = sum+self_protecting*q1(x-1,y-1);
        elseif q2(x-1,y-1)==2
            sum = sum+protecting_others*q1(x-1,y-1);    
        end
    end
    if x>1 && y<k 
        if q2(x-1,y+1)==0
            sum = sum+no_security_measures*q1(x-1,y+1);
        elseif q2(x-1,y+1)==1
            sum = sum+self_protecting*q1(x-1,y+1);
        elseif q2(x-1,y+1)==2
            sum = sum+protecting_others*q1(x-1,y+1);    
        end
        max_sum_temp = max_sum_temp+1;
    end
    if x<w && y>1 
        if q2(x+1,y-1)==0
            sum = sum+no_security_measures*q1(x+1,y-1);
        elseif q2(x+1,y-1)==1
            sum = sum+self_protecting*q1(x+1,y-1);
        elseif q2(x+1,y-1)==2
            sum = sum+protecting_others*q1(x+1,y-1);    
        end
        max_sum_temp = max_sum_temp+1;
    end
    if x<w && y<k
        if q2(x+1,y+1)==0
            sum = sum+no_security_measures*q1(x+1,y+1);
        elseif q2(x+1,y+1)==1
             sum = sum+self_protecting*q1(x+1,y+1);
        elseif q2(x+1,y+1)==2
             sum = sum+protecting_others*q1(x+1,y+1);   
        end
        max_sum_temp = max_sum_temp+1;
    end
    
neighbors_sum = sum;
max_sum = max_sum_temp;
   
end
          
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % zapisanie danych z EditField-ow:
            set(app.tabela_disp,'Data',[]);
            clear param_struct;
            param_struct.pq_i.Value = app.pq_iEditField.Value;
            param_struct.pq_is.Value = app.pq_isEditField.Value;
            param_struct.pi_is.Value = app.pi_isEditField.Value;
            param_struct.pi_r.Value = app.pi_rEditField.Value;
            param_struct.ps_ih.Value = app.ps_ihEditField.Value;
            param_struct.pis_ih.Value = app.pis_ihEditField.Value;
            param_struct.pis_r.Value = app.pis_rEditField.Value;
            param_struct.pih_r.Value = app.pih_rEditField.Value;
            param_struct.pih_d.Value = app.pih_dEditField.Value;
            param_struct.pih_h.Value = app.pih_hEditField.Value;
            param_struct.ogr_sum.Value = app.ogr_sumEditField.Value;
            param_struct.rozmiar.Value = app.rozmiarEditField.Value;
            param_struct.sym_wer.Value = app.sym_werDropDown.Value;
            save('Hiszpania_parametry.mat','param_struct');
            
            run Hiszpania\wczyt_danych.m; % wczytywanie danych z excela
            run Hiszpania\konfig_pocz.m; % konfiguracja poczatkowa
            run Hiszpania\f_przejscia.m; % funkcje przejscia
            run Hiszpania\wyniki.m; % dane posrednie i koncowe
            plot_glowny(app);
            tabela(app);
        end

        % Button pushed function: DodatkowewykresyButton
        function DodatkowewykresyButtonPushed(app, event)
            load('Hiszpania\calosc.mat');
            run Hiszpania\wykresy.m; % wykresy jako osobne figures
        end

        % Button pushed function: ITLoadButton
        function ITLoadButtonPushed(app, event)

            filterspec = {'*.xlsx','All Files'};
            [file, path] = uigetfile(filterspec);
            filename = fullfile(path, file);
            
            %uialert(app.UIFigure, file, 'Image Error');
            
            if strcmp(file,'Coronavirus_Italy.xlsx')
                [num] = xlsread(filename);
                rozmiar=500;
                [world,dzielnik] = fCreateWorld_Kamil(num, app.ITStartdayEditField.Value, rozmiar);
                update_plot_IT(app,world,num,dzielnik);
            else
                uialert(app.SymulacjaEpidemii, 'Wprowadź dane wejściowe z Włoch', 'Error');
            end
            
        end

        % Button pushed function: AustriaStart
        function AustriaStartButtonPushed(app, event)
            AustriaStopFlag = false;
        data = readmatrix('Austria\data_17_03_AUT.csv');
        
        %____dane z 30 kwietnia 1000x1000
        %data = readmatrix('data_30_04_AUT.csv');

        %____wizualizacja danych wejściowych_______________________________________


        imagesc(app.AustriaVis,data, [-1 6])
        colormap(app.AustriaVis, [0 0 0; 255/255 255/255 255/255; 158/255 201/255 250/255; 255/255 194/255 26/255; 255/255 152/255 43/255; 255/255 112/255 71/255; 255/255 0 53/255])
        title (app.AustriaVis, sprintf('Iteracja: 0'))

        maxiter = str2double(app.maxiterEditField.Value);

        temp_size = size(data);
        map_size = temp_size(1);


        %_____parametry symulacji__________________________________________________


        no_security_measures = str2double(app.no_security_measuresEditField.Value);
        self_protecting = str2double(app.self_protectingEditField.Value);
        protecting_others = str2double(app.protecting_othersEditField.Value);
        neighbours_threshold = str2double(app.neighbours_thresholdEditField.Value);
        miracle = str2double(app.miracleEditField.Value);
        death_rate = str2double(app.death_rateEditField.Value);

        self_protecting_threshold = str2double(app.self_protecting_thresholdEditField.Value);
        protecting_others_threshold = str2double(app.protecting_others_thresholdEditField.Value);


        %____dane testowe__________________________________________________________
        map_q1 = zeros(map_size);
        map_q2 = data;

        %____liczniki stanów_______________________________________________________

        %____q2
        dead_cnt = zeros(1,maxiter);
        healthy_cnt = zeros(1,maxiter);
        recovered_cnt = zeros(1,maxiter);
        in_quarantine_cnt = zeros(1,maxiter);
        infected_cnt = zeros(1,maxiter);
        in_hospital_cnt = zeros(1,maxiter);
        sick_cnt = zeros(1,maxiter);
        %____q1
        no_sec_cnt = zeros(1,maxiter);
        self_prot_snt = zeros(1,maxiter);
        prot_oth_cnt = zeros(1,maxiter);
               
        %____pojedyncze liczniki
        dead = 0;
        healthy = 0;
        recovered = 0;
        in_quarantine = 0;
        infected = 0;
        in_hospital = 0;
        sick = 0;
        no_sec = 0;
        self_prot = 0;
        prot_oth = 0;


%____główna pętla__________________________________________________________

for n=1:maxiter

    
    next_q1=zeros(map_size);
    next_q2=zeros(map_size);
    
    for i=1:map_size
        for j=1:map_size
            [n_sum, n_max] = NeighboursSum(app, map_q2, map_q1, i,j);
            base_prob = n_sum / (n_max*5);%wspĂłĹ‚czynnik stanu sÄ…siadĂłw


%________next_q2___________________________________________________________
            
            switch map_q2(i,j)%stwierdzanie next_q2
                
                
                case -1 %dead
                    next_q2(i,j)=-1;%dead
                    
                    
                case 0 %healthy
                    if map_q1(i,j) == 0%no security measures
                        if base_prob*no_security_measures<neighbours_threshold%stwierdzenie wpĹ‚ywu poziomu sÄ…siadĂłw
                            if rand()> no_security_measures*base_prob %losowanie infekcji
                                next_q2(i,j)=0;%healthy
                            else
                                next_q2(i,j)=3;%infected
                            end
                        else
                            next_q2(i,j)=3;%infected
                        end
                    elseif map_q1(i,j) == 1%self protecting
                        if base_prob*self_protecting<neighbours_threshold%poziom wpĹ‚ywu sÄ…siadĂłw przy self protecting
                            if rand()>self_protecting*base_prob %losowanie infekcji
                                next_q2(i,j)=0;%healthy
                            else
                                next_q2(i,j)=3;%infected
                            end
                        else
                            next_q2(i,j)=3;%infected
                        end                      
                    end
                    
                    
                case 1 %recovered
                    if map_q1(i,j) == 0%no security measures
                        if base_prob*no_security_measures<neighbours_threshold
                            next_q2(i,j)=1;%recovered - model nie zakĹ‚ada ponownego zachorowania
                        else
                            next_q2(i,j)=1;%recovered - model nie zakĹ‚ada ponownego zachorowania
                        end
                    elseif map_q1(i,j) == 2%protecting others
                        if base_prob*protecting_others<neighbours_threshold
                            next_q2(i,j)=1;%recovered - model nie zakĹ‚ada ponownego zachorowania
                        else
                            next_q2(i,j)=1;%recovered - model nie zakĹ‚ada ponownego zachorowania
                        end
                    end
                    
                    
                case 2 %in quarantine
                   %moĹĽe byÄ‡ dead, in hospital, recovered
                   
                   if base_prob*protecting_others<neighbours_threshold
                       if rand()>protecting_others*base_prob %losowanie infekcji
                            next_q2(i,j)=2;%in quarantine
                       else
                            next_q2(i,j)=4;%in hospital
                       end
                   end
                   fate = rand();
                   if fate>miracle %losowanie cudu
                       next_q2(i,j)=1;%recovered
                   end
                   if fate<death_rate %losowanie Ĺ›mierci
                       next_q2(i,j)=-1;%dead
                   end
                     
                   
                case 3 %infected
                    %moĹĽe byÄ‡ sick, in quarantine, recovered
                    
                    if map_q1(i,j) == 0%no security measures
                        if base_prob*no_security_measures<neighbours_threshold
                            if rand()>no_security_measures*base_prob %losowanie infekcji
                                next_q2(i,j)=3;%infected
                            else
                                next_q2(i,j)=5;%sick
                            end
                        else
                            next_q2(i,j)=5;%sick
                        end
                    elseif map_q1(i,j) == 2%protecting others
                        if base_prob*protecting_others<neighbours_threshold
                            if rand()>protecting_others*base_prob %losowanie infekcji
                                next_q2(i,j)=3;%infected
                            else
                                next_q2(i,j)=2;%in quarantine
                            end
                        else
                            next_q2(i,j)=5;%sick
                        end
                    end
                    if rand()>miracle %losowanie cudu
                       next_q2(i,j)=1;%recovered
                    end
                    
                    
                case 4 %in hospital
                    %moĹĽe byÄ‡ dead albo recovered
                    
                    next_q2(i,j)=4;%in hospital

                    fate = rand();
                    if fate>miracle %losowanie cudu
                        next_q2(i,j)=1;%recovered
                    end
                    if fate<death_rate %losowanie Ĺ›mierci
                        next_q2(i,j)=-1;%dead
                    end
                    
                case 5 %sick
                    %moĹĽe byÄ‡ dead, recovered, in quarantine, in hospital
                    
                    if map_q1(i,j) == 0%no security measures
                        if base_prob*no_security_measures<neighbours_threshold
                            if rand()>no_security_measures*base_prob %losowanie infekcji
                                next_q2(i,j)=2;%in quarantine
                            else
                                next_q2(i,j)=4;%in hospital
                            end
                        else
                            next_q2(i,j)=5;%sick
                        end
                        
                    elseif map_q1(i,j) == 2%protecting others
                        if base_prob*protecting_others<neighbours_threshold
                            if rand()>protecting_others*base_prob %losowanie infekcji
                                next_q2(i,j)=2;%in quarantine
                            else
                                next_q2(i,j)=4;%in hospital
                            end
                        else
                            next_q2(i,j)=5;%sick
                        end
                    end  
                    
                    fate = rand();
                    if fate>miracle %losowanie cudu
                        next_q2(i,j)=1;%recovered
                    end
                    if fate<death_rate %losowanie Ĺ›mierci
                        next_q2(i,j)=-1;%dead
                    end
            end


%________next_q1___________________________________________________________
            
            switch next_q2(i,j)%stwierdzanie next_q1
                
                
                case -1 %dead
                    next_q1(i,j)=0;%no security measures
                    
                    
                case 0 %healthy
                    if map_q1(i,j) == 0%no security measures
                        if rand() > self_protecting_threshold%losowanie
                            next_q1(i,j)=0;%no security measures
                        else
                            next_q1(i,j)=1;%self protecting
                        end
                    else
                        next_q1(i,j)=1;%self protecting
                    end

                    
                case 1 %recovered
                    if map_q1(i,j) == 0%no security measures
                        if rand() > protecting_others_threshold%losowanie
                            next_q1(i,j)=0;%no security measures
                        else
                            next_q1(i,j)=2;%protecting others
                        end
                    else
                        next_q1(i,j)=2;%protecting others
                    end

                    
                case 2 %in quarantine
                    next_q1(i,j)=2;%protecting others

                    
                case 3 %infected
                    if map_q1(i,j) == 0%no security measures
                        if rand() > protecting_others_threshold%losowanie
                            next_q1(i,j)=0;%no security measures
                        else
                            next_q1(i,j)=2;%protecting others
                        end
                    else
                        next_q1(i,j)=2;%protecting others
                    end

                    
                case 4 %in hospital
                    next_q1(i,j)=2;%protecting others

                    
                case 5 %sick
                    if map_q1(i,j) == 0%no security measures
                        if rand() > protecting_others_threshold%losowanie
                            next_q1(i,j)=0;%no security measures
                        else
                            next_q1(i,j)=2;%protecting others
                        end
                    else
                        next_q1(i,j)=2;%protecting others
                    end
            end


%______update liczników____________________________________________________
            switch next_q2(i,j)
                case -1
                    dead = dead+1;
                case 0
                    healthy = healthy+1;

                case 1
                    recovered = recovered+1;

                case 2
                    in_quarantine = in_quarantine+1;

                case 3
                    infected = infected+1;

                case 4
                    in_hospital = in_hospital+1;

                case 5
                    sick = sick+1;
            end
            
            switch next_q1(i,j)
                case 0
                    no_sec = no_sec+1;
                case 1
                    self_prot = self_prot+1;
                case 2
                    prot_oth = prot_oth+1;
            end


        end
    end


%____update liczników______________________________________________________
    
    dead_cnt(n) = dead;
    healthy_cnt(n) = healthy;
    recovered_cnt(n) = recovered;
    in_quarantine_cnt(n) = in_quarantine;
    infected_cnt(n) = infected;
    in_hospital_cnt(n) = in_hospital;
    sick_cnt(n) = sick;
    no_sec_cnt(n) = no_sec;
    self_prot_snt(n) = self_prot;
    prot_oth_cnt(n) = prot_oth;
    
    
    app.AustriaTable.Data = [n,dead,healthy,recovered,in_quarantine,infected,in_hospital,sick];


%______koniec przejĹ›cia po mapie__________________________________________
    
    map_q1 = next_q1;
    map_q2 = next_q2;


%__________wizualizacje____________________________________________________
    
    %imshow(map_q2+1,[])
    %figure(1)
    imagesc(app.AustriaVis,map_q2, [-1 6])
    colormap(app.AustriaVis, [0 0 0; 255/255 255/255 255/255; 158/255 201/255 250/255; 255/255 194/255 26/255; 255/255 152/255 43/255; 255/255 112/255 71/255; 255/255 0 53/255])
    title (app.AustriaVis, sprintf('Iteracja: %d',n))
    
    drawnow        
    
%____zerowanie liczników___________________________________________________

%____q2
    dead = 0;
    healthy = 0;
    recovered = 0;
    in_quarantine = 0;
    infected = 0;
    in_hospital = 0;
    sick = 0;
%____q1
    no_sec = 0;
    self_prot = 0;
    prot_oth = 0;
 
    
end
        end

        % Button pushed function: StartAustralia
        function StartAustraliaButtonPushed(app, event)
dataAustralia = readmatrix("Australia\corona_data.xlsx");
% data = num2cell(data);
app.TableAustralia.Data = [1, dataAustralia(1,3:6)]; 
            
% indeksy q1
ind_h = 1;
ind_r = 2;
ind_s = 3;
ind_i_q = 4;
ind_i_a_s = 5;
ind_in = 6;
ind_i_h = 7;
ind_d = 8;

% indeksy q2
ind_n_s_m = 1;
ind_ig = 2;
ind_s_p = 3;
ind_p_o = 4;
ind_o_p = 5;

rozmiar = 200; % rozmiar planszy

kraj = ones(rozmiar,rozmiar);	   % biezaca plansza
liczbaCykli = app.Maxiteracji.Value;                  % ile iteracji zrobic

% argumenty (procentowa szansa na dany stan): (kraj, i_q, in, s, i_a_s, i_h, r, d);
kraj = fWczytajKraj(kraj, 1, 1, 1, 0, 0, 0, 0);
imagesc(app.WykresAustralia, kraj, [1 8]); 
colorbar(app.WykresAustralia); 
title(app.WykresAustralia, 'cykl 0');

% prawdopodobieństwo testu
test_prawd = app.Testy.Value; %0-10 (robione nikomu - robione wszystkim)
informacje = app.Politykainformacyjna.Value; %0-10
ograniczenia = app.Ograniczenia.Value; %0-10

%Prawdopodobieństwo przejść między stanami
prawdop = fMacierzPrawdZmianyStanu(test_prawd);
licznik = zeros(rozmiar,rozmiar, 4); 

% stan q2 komórki
macierz_q2 = zeros(rozmiar,rozmiar);
for w1=1:rozmiar
        for k1=1:rozmiar
            macierz_q2(w1,k1) = fWyliczQ2(kraj(w1,k1), informacje, ograniczenia);
        end
end

%% pętla główna
for n=1:liczbaCykli   
    krajNext= ones(rozmiar,rozmiar); % tworzymy plansze do odtwarzania stanu "za chwile"
    for w=1:rozmiar
        for k=1:rozmiar
            sasiedzi_q1 = fPoliczSasiadowAustralia(kraj,w,k, 8);
            sasiedzi_q2 = fPoliczSasiadowAustralia(macierz_q2,w,k, 5);
            
            % następny stan wyliczamy ze względu na sąsiadów, prawdopodobieństwo przejść między stanami, stan w Q2,
            % długość trwania choroby/kwarantanny
            krajNext(w,k) = fStanNastepny(kraj(w,k), sasiedzi_q1, sasiedzi_q2, prawdop(kraj(w,k),:), macierz_q2(w,k), licznik(w,k,:));
            
            % jeśli stan się zmieni, to losujemy nową (dopuszczalną) wartość q2 
            if (krajNext(w,k) ~= kraj(w,k))
                macierz_q2(w,k, :) = fWyliczQ2(kraj(w,k), informacje, ograniczenia);
            end
            kraj(w,k) = krajNext(w,k);
            % zapis dni na kwarantannie / w szpitalu / w chorobie:
            if kraj(w,k) == ind_s
                licznik(w,k, 1) = licznik(w,k, 1) + 1;
            elseif kraj(w,k) == ind_i_q
                licznik(w,k, 2) = licznik(w,k, 2) + 1;
            elseif kraj(w,k) == ind_in
                licznik(w,k, 3) = licznik(w,k, 3) + 1;
            elseif kraj(w,k) == ind_i_h
                licznik(w,k, 4) = licznik(w,k, 4) + 1;
            end
        end
    end
    imagesc(app.WykresAustralia, ...
        kraj, [1 8]); colorbar(app.WykresAustralia); title(app.WykresAustralia, ['cykl ' num2str(n)]);
    if (n<138) 
        app.TableAustralia.Data = [n, dataAustralia(n,3:6)]; 
    end
    pause(0.5);
end
        end

        % Button pushed function: STARTButton_SEIR
        function STARTButton_SEIRPushed(app, event)
% Dane rzeczywiste
data = readmatrix("dataAustria.csv");
D_real = data(20:182,7);
Cases_real = data(20:182,4);

%% SEIR
% Time definition
dt = 1; % time step
N = app.DugosymulacjiEditField.Value;
t = [0:N-1].*dt;
Npop= 8859000; % population (8.859 millions)

E0 = 100; % Initial number of exposed
I0 = 30; % Initial number of infectious cases non-quarantined
SI0 = 1;
Q0 = 10; % Initial number of infectious that have been quanrantined
IH0 = 0;
R0 = 0; % Initial number of recovereds
D0 = 0; % Initial number of deads
u=app.uEditField.Value;
a=app.aEditField.Value; b=app.bEditField.Value;
c=app.cEditField.Value; d=app.dEditField.Value; e=app.eEditField.Value; 
f=app.fEditField.Value; g=app.gEditField.Value; h=app.hEditField.Value; i=app.iEditField.Value; 
j=app.jEditField.Value; k=app.kEditField.Value; l=app.lEditField.Value;
m=app.mEditField.Value; n=app.nEditField.Value; 
[S,E,I,SI,Q,IH,R,D] = SEIQRDP_with_F(u,a,b,c,d,e,f,g,h,i,j,k,l,m,n,Npop,E0,I0,SI0,Q0,R0,R0,D0,t);
app.SEIRTable.Data = [N, D(end), SI(end)+Q(end)+IH(end)];

%% Wykresy
plot(app.DeadPlot, D); hold(app.DeadPlot, 'on'); plot(app.DeadPlot, D_real);
plot(app.CasesPlot, t, SI + Q + IH); hold(app.CasesPlot, 'on'); plot(app.CasesPlot, Cases_real); 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SymulacjaEpidemii = uifigure('Visible', 'off');
            app.SymulacjaEpidemii.Name = 'Symulacja epidemii COVID-19 w wybranych państwach';
            app.TabGroup = uitabgroup(app.SymulacjaEpidemii);
            app.WochyTab = uitab(app.TabGroup);
            app.WochyTab.Title = 'Włochy';
            app.ITAxes = uiaxes(app.WochyTab);
            title(app.ITAxes, 'Wyniki symulacji')
            xlabel(app.ITAxes, '')
            ylabel(app.ITAxes, '')
            app.ITAxes.XTick = [];
            app.ITAxes.YTick = [];
            app.ITSymulacjaepidemiidlaWochLabel = uilabel(app.WochyTab);
            app.ITSymulacjaepidemiidlaWochLabel.Text = 'Symulacja epidemii dla Włoch';
            app.ITWczytanieprzypadkupocztkowegoLabel = uilabel(app.WochyTab);
            app.ITWczytanieprzypadkupocztkowegoLabel.Text = 'Wczytanie przypadku poczatkowego';
            app.ITLoadButton = uibutton(app.WochyTab, 'push');
            app.ITLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ITLoadButtonPushed, true);
            app.ITLoadButton.Text = {'START'; ''};
            app.ITWynikiLabel = uilabel(app.WochyTab);
            app.ITWynikiLabel.Text = 'Wyniki';
            app.ITRecoveredTextAreaLabel = uilabel(app.WochyTab);
            app.ITRecoveredTextAreaLabel.Text = {'Wyzdrowienia'; ''};
            app.ITRecoveredTextArea = uitextarea(app.WochyTab);
            app.ITDeadTextAreaLabel = uilabel(app.WochyTab);
            app.ITDeadTextAreaLabel.Text = 'Zgony';
            app.ITDeadTextArea = uitextarea(app.WochyTab);
            app.Dziepocztkowyepidemiiodpocztkowejdatyzbieraniadanych2802Label = uilabel(app.WochyTab);
            app.Dziepocztkowyepidemiiodpocztkowejdatyzbieraniadanych2802Label.Text = {'Dzień początkowy epidemii (od początkowej daty zbierania danych - 28.02)'; ''};
            app.ITStartdayEditField = uieditfield(app.WochyTab, 'numeric');
            app.ITStartdayEditField.Value = 27;
            app.LiczbaiteracjidnisymulowanychEditFieldLabel = uilabel(app.WochyTab);
            app.LiczbaiteracjidnisymulowanychEditFieldLabel.Text = 'Liczba iteracji (dni symulowanych)';
            app.ITLiczbaiteracjidnisymulowanychEditField = uieditfield(app.WochyTab, 'numeric');
            app.ITLiczbaiteracjidnisymulowanychEditField.Value = 40;
            app.ITProszwczytasterowaniaprawdopodobiestwaLabel = uilabel(app.WochyTab);
            app.ITProszwczytasterowaniaprawdopodobiestwaLabel.Text = 'Proszę wczytać sterowania (prawdopodobieństwa):';
            app.ITDzienSymulacjiTextAreaLabel = uilabel(app.WochyTab);
            app.ITDzienSymulacjiTextAreaLabel.Text = 'Dzień symulacji';
            app.ITDzienSymulacjiTextArea = uitextarea(app.WochyTab);
            app.ITPrawdopodobienstwowystapieniaobjawowTextAreaLabel = uilabel(app.WochyTab);
            app.ITPrawdopodobienstwowystapieniaobjawowTextAreaLabel.Text = 'Prawdopodobieństwo wystąpienia objawów';
            app.ITPrawdopodobienstwowykonaniatestuTextAreaLabel = uilabel(app.WochyTab);
            app.ITPrawdopodobienstwowykonaniatestuTextAreaLabel.Text = 'Prawdopodobieństwo wykonania testu';
            app.ITNapis1TextAreaLabel = uilabel(app.WochyTab);
            app.ITNapis1TextAreaLabel.Text = 'Prawdopodobieństwo zachorowania na COVID-19 - obliczane na podstawie ';
            app.ITNapis2 = uilabel(app.WochyTab);
            app.ITNapis2.Text = 'sterowań w kraju (ograniczeń w poruszaniu, polityki inf., odp. społecznej). ';
            app.ITPrawdopodobienstwowykonaniatestuEditField = uieditfield(app.WochyTab, 'text');
            app.ITPrawdopodobienstwowykonaniatestuEditField.Value = '0.8';
            app.ITPrawdopodobienstwowystapieniaobjawowEditField = uieditfield(app.WochyTab, 'text');
            app.ITPrawdopodobienstwowystapieniaobjawowEditField.Value = '0.3';
            app.ITIlosctestowTextAreaLabel = uilabel(app.WochyTab);
            app.ITIlosctestowTextAreaLabel.Text = 'Ilość losowo wykonanych testów (dziennie)';
            app.ITIlosctestowEditField = uieditfield(app.WochyTab, 'text');
            app.ITIlosctestowEditField.Value = '1000';
            app.ITProszwczytasterowaniatestyLabel = uilabel(app.WochyTab);
            app.ITProszwczytasterowaniatestyLabel.Text = 'Proszę wczytać sterowania (ilość losowo wykonanych testów):';
            app.ITSterowaniaLabel = uilabel(app.WochyTab);
            app.ITSterowaniaLabel.Text = 'Sterowania';
            app.HiszpaniaTab = uitab(app.TabGroup);
            app.HiszpaniaTab.Title = 'Hiszpania';
            app.pq_iEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.pq_iEditFieldLabel.Text = 'pq_i';
            app.pq_iEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.pq_iEditField.Value = '0.019';
            app.pq_isEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.pq_isEditFieldLabel.Text = 'pq_is';
            app.pq_isEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.pq_isEditField.Value = '0.013';
            app.pi_isEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.pi_isEditFieldLabel.Text = 'pi_is';
            app.pi_isEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.pi_isEditField.Value = '0.046';
            app.pi_rEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.pi_rEditFieldLabel.Text = 'pi_r';
            app.pi_rEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.pi_rEditField.Value = '0.01';
            app.ps_ihEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.ps_ihEditFieldLabel.Text = 'ps_ih';
            app.ps_ihEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.ps_ihEditField.Value = '0.57588';
            app.pis_ihEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.pis_ihEditFieldLabel.Text = 'pis_ih';
            app.pis_ihEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.pis_ihEditField.Value = '0.058';
            app.pis_rEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.pis_rEditFieldLabel.Text = 'pis_r';
            app.pis_rEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.pis_rEditField.Value = '0.031';
            app.pih_rEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.pih_rEditFieldLabel.Text = 'pih_r';
            app.pih_rEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.pih_rEditField.Value = '0.0222';
            app.pih_dEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.pih_dEditFieldLabel.Text = 'pih_d';
            app.pih_dEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.pih_dEditField.Value = '0.0033';
            app.pih_hEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.pih_hEditFieldLabel.Text = 'pih_h';
            app.pih_hEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.pih_hEditField.Value = '0.78';
            app.ogr_sumEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.ogr_sumEditFieldLabel.Text = 'ogr_sum';
            app.ogr_sumEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.ogr_sumEditField.Value = '1';
            app.rozmiarEditFieldLabel = uilabel(app.HiszpaniaTab);
            app.rozmiarEditFieldLabel.Text = 'rozmiar';
            app.rozmiarEditField = uieditfield(app.HiszpaniaTab, 'text');
            app.rozmiarEditField.Value = '100';
            app.STARTButton = uibutton(app.HiszpaniaTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.wstepLabel = uilabel(app.HiszpaniaTab);
            app.wstepLabel.Text = {'Wstęp:'; 'Witam w symulacji epidemii COVID-19 w Hiszpanii. Proszę wybrać sterowanie następującymi parametrami:'; '    1) pq_i - prawdopodobieństwo przejścia ze stanu in_quarantine do stanu infected,'; '    2) pq_is - prawdopodobieństwo przejścia ze stanu in_quarantine do stanu infected_and_sick,'; '    3) pi_is - prawdopodobieństwo przejścia ze stanu infected do stanu infected_and_sick,'; '    4) pi_r - prawdopodobieństwo przejścia ze stanu infected do stanu recovered,'; '    5) ps_ih - prawdopodobieństwo przejścia ze stanu sick do stanu in_hospital,'; '    6) pis_ih - prawdopodobieństwo przejścia ze stanu infected_and_sick do stanu in_hospital,'; '    7) pis_r - prawdopodobieństwo przejścia ze stanu infected_and_sick do stanu recovered,'; '    8) pih_r - prawdopodobieństwo przejścia ze stanu in_hospital do stanu recovered,'; '    9) pih_d - prawdopodobieństwo przejścia ze stanu in_hospital do stanu dead,'; '    10) pih_h - prawdopodobieństwo przejścia ze stanu in_hospital do stanu healthy,'; '    11) ogr_sum - ograniczenie na liczbę sąsiadów w funkcjach przejść typu zliczającego,'; '    12) rozmiar - rozmiar planszy,'; '    13) sym_wer - wersja symulacji: 1 - do końca kwietnia, 2 - do końca września.'};
            app.InformacjezwrotneLabel = uilabel(app.HiszpaniaTab);
            app.InformacjezwrotneLabel.Text = {'Uwaga:'; 'Szczegółowe informacje zwrotne znajdują się w Command Window. '};
            app.plot_gl = uiaxes(app.HiszpaniaTab);
            title(app.plot_gl, 'Wyniki symulacji')
            xlabel(app.plot_gl, '')
            ylabel(app.plot_gl, '')
            app.plot_gl.XTick = [];
            app.plot_gl.YTick = [];
            app.tabela_disp = uitable(app.HiszpaniaTab);
            app.tabela_disp.ColumnName = {'dzień symulacji'; 'healthy'; 'infected/infected and sick'; 'recovered'; 'dead'};
            app.tabela_disp.RowName = {''};
            app.wyniki_w_licz = uilabel(app.HiszpaniaTab);
            app.wyniki_w_licz.Text = 'Wyniki symulacji w liczbach';
            app.Ozn_stanow = uilabel(app.HiszpaniaTab);
            app.Ozn_stanow.Text = {'1. healthy (0):'; 'no_security_measures (0.1)'; 'self_protecting (0.3)'; '2. in_quarantine (1):'; 'organizing_protection (1.5)'; '3. infected (2):'; 'no_security_measures (2.1)'; 'infecting (2.2)'; 'protecting_others (2.4)'; '4. sick (3):'; 'no_security_measures (3.1)'; 'self_protecting (3.3)'; 'protecting_others (3.4)'; '5. infected_and_sick (4):'; 'no_security_measures (4.1)'; 'infecting (4.2)'; 'protecting_others (4.4)'; '6. in_hospital (5):'; 'organizing_protection (5.5)'; '7. recovered (6):'; 'no_security_measures (6.1)'; 'self_protecting (6.3)'; '8. dead (7):'; 'organizing_protection (7.5)'};
            app.Ozn_stanow_tytul = uilabel(app.HiszpaniaTab);
            app.Ozn_stanow_tytul.Text = 'Oznaczenia stanów:';
            app.DodatkowewykresyButton = uibutton(app.HiszpaniaTab, 'push');
            app.DodatkowewykresyButton.ButtonPushedFcn = createCallbackFcn(app, @DodatkowewykresyButtonPushed, true);
            app.DodatkowewykresyButton.Text = 'Dodatkowe wykresy';
            app.sym_werLabel = uilabel(app.HiszpaniaTab);
            app.sym_werLabel.Text = 'sym_wer';
            app.sym_werDropDown = uidropdown(app.HiszpaniaTab);
            app.sym_werDropDown.Items = {'1', '2'};
            app.sym_werDropDown.Value = '1';
            app.AustriaTab = uitab(app.TabGroup);
            app.AustriaTab.Title = 'Austria';
            app.AustriaStart = uibutton(app.AustriaTab, 'push');
            app.AustriaStart.ButtonPushedFcn = createCallbackFcn(app, @AustriaStartButtonPushed, true);
            app.AustriaStart.Text = 'START';
            app.no_security_measuresEditFieldLabel = uilabel(app.AustriaTab);
            app.no_security_measuresEditFieldLabel.Text = 'no_security_measures';
            app.no_security_measuresEditField = uieditfield(app.AustriaTab, 'text');
            app.no_security_measuresEditField.Value = '0.9';
            app.self_protectingEditFieldLabel = uilabel(app.AustriaTab);
            app.self_protectingEditFieldLabel.Text = 'self_protecting';
            app.self_protectingEditField = uieditfield(app.AustriaTab, 'text');
            app.self_protectingEditField.Value = '0.6';
            app.protecting_othersEditFieldLabel = uilabel(app.AustriaTab);
            app.protecting_othersEditFieldLabel.Text = 'protecting_others';
            app.protecting_othersEditField = uieditfield(app.AustriaTab, 'text');
            app.protecting_othersEditField.Value = '0.4';
            app.neighbours_thresholdEditFieldLabel = uilabel(app.AustriaTab);
            app.neighbours_thresholdEditFieldLabel.Text = 'neighbours_threshold';
            app.neighbours_thresholdEditField = uieditfield(app.AustriaTab, 'text');
            app.neighbours_thresholdEditField.Value = '0.3';
            app.miracleEditFieldLabel = uilabel(app.AustriaTab);
            app.miracleEditFieldLabel.Text = 'miracle';
            app.miracleEditField = uieditfield(app.AustriaTab, 'text');
            app.miracleEditField.Value = '0.95';
            app.death_rateEditFieldLabel = uilabel(app.AustriaTab);
            app.death_rateEditFieldLabel.Text = 'death_rate';
            app.death_rateEditField = uieditfield(app.AustriaTab, 'text');
            app.death_rateEditField.Value = '0.02';
            app.self_protecting_thresholdEditFieldLabel = uilabel(app.AustriaTab);
            app.self_protecting_thresholdEditFieldLabel.Text = 'self_protecting_threshold';
            app.self_protecting_thresholdEditField = uieditfield(app.AustriaTab, 'text');
            app.self_protecting_thresholdEditField.Value = '0.7';
            app.protecting_others_thresholdEditFieldLabel = uilabel(app.AustriaTab);
            app.protecting_others_thresholdEditFieldLabel.Text = 'protecting_others_threshold';
            app.protecting_others_thresholdEditField = uieditfield(app.AustriaTab, 'text');
            app.protecting_others_thresholdEditField.Value = '0.9';
            app.maxiterEditFieldLabel = uilabel(app.AustriaTab);
            app.maxiterEditFieldLabel.Text = 'maxiter';
            app.maxiterEditField = uieditfield(app.AustriaTab, 'text');
            app.maxiterEditField.Value = '44';
            app.AustriaVis = uiaxes(app.AustriaTab);
            title(app.AustriaVis, 'Wizualizacja')
            xlabel(app.AustriaVis, '')
            ylabel(app.AustriaVis, '')
            app.AustriaVis.XTick = [];
            app.AustriaVis.YTick = [];
            app.AustriaTable = uitable(app.AustriaTab);
            app.AustriaTable.ColumnName = {'Iter'; 'dead'; 'healthy'; 'recovered'; 'in quarantine'; 'infected'; 'in hospital'; 'sick'};
            app.AustriaTable.RowName = {};
            app.SymulacjaepidemiidlaAustriiLabel = uilabel(app.AustriaTab);
            app.SymulacjaepidemiidlaAustriiLabel.Text = 'Symulacja epidemii dla Austrii';
            app.WmodelukomrkamoeosigajesenzsiedmiustanwLabel = uilabel(app.AustriaTab);
            app.WmodelukomrkamoeosigajesenzsiedmiustanwLabel.Text = {'W modelu komórka może osiągać jeden z siedmiu stanów: '; 'dead, healthy, recovered, in_quarantine, infected, in_hospital, '; 'sick'; 'oraz jeden ze stanów: '; 'no security measures, self protecting, protecting others.'; ''; 'Parametry symulacji wpływają w następujący sposób:'; ''; 'maxiter - ilość dni w symulacji'; ''; 'no_security_measures - skalowanie prawdopodobieństwa '; 'przejścia dla komórki w stanie no security measures'; ''; 'self_protecting - skalowanie prawdopodobieństwa '; 'przejścia dla komórki w stanie self protecting'; ''; 'protecting_others - skalowanie prawdopodobieństwa '; 'przejścia dla komórki w stanie protecting others'; ''; 'neighbours_threshold - poziom stanu sąsiadów, powyżej '; 'któego komórka przechodzi do następnego stanu'; ''; 'miracle - poziom, powyżej którego po losowaniu '; 'komórka przechodzi do stanu recovered'; ''; 'death_rate - poziom, poniżej którego po losowaniu '; 'komórka przechodzi do stanu dead'; ''; 'self_protecting_threshold - poziom, powyżej którego '; 'po losowaniu komórka przechodzi do stanu self protecting'; ''; 'protecting_others_threshold - poziom, powyżej którego '; 'po losowaniu komórka przechodzi do stanu protecting others'};
            app.AustraliaTab = uitab(app.TabGroup);
            app.AustraliaTab.Title = 'Australia';
            app.Title = uilabel(app.AustraliaTab);
            app.Title.Text = 'Symulacja rozwoju epidemii Covid-19 dla Australii';
            app.Text1 = uilabel(app.AustraliaTab);
            app.Text1.Text = {'Numery Q1 odpowiadają wartościom na colormapie.'; 'Wprowadź wartości sterowań:'};
            app.StartAustralia = uibutton(app.AustraliaTab, 'push');
            app.StartAustralia.ButtonPushedFcn = createCallbackFcn(app, @StartAustraliaButtonPushed, true);
            app.StartAustralia.Text = 'START';
            app.Ograniczenia010SpinnerLabel_2 = uilabel(app.AustraliaTab);
            app.Ograniczenia010SpinnerLabel_2.Text = 'Ograniczenia (0-10)';
            app.Ograniczenia = uispinner(app.AustraliaTab);
            app.Ograniczenia.Limits = [0 10];
            app.Ograniczenia.Value = 6;
            app.Testy010SpinnerLabel_2 = uilabel(app.AustraliaTab);
            app.Testy010SpinnerLabel_2.Text = 'Testy (0-10)';
            app.Testy = uispinner(app.AustraliaTab);
            app.Testy.Limits = [0 10];
            app.Testy.Value = 6;
            app.Politykainformacyjna110SpinnerLabel_2 = uilabel(app.AustraliaTab);
            app.Politykainformacyjna110SpinnerLabel_2.Text = 'Polityka informacyjna (1-10)';
            app.Politykainformacyjna = uispinner(app.AustraliaTab);
            app.Politykainformacyjna.Limits = [0 10];
            app.Politykainformacyjna.Value = 8;
            app.MaxiteracjiEditFieldLabel_2 = uilabel(app.AustraliaTab);
            app.MaxiteracjiEditFieldLabel_2.Text = 'Max. iteracji';
            app.Maxiteracji = uieditfield(app.AustraliaTab, 'numeric');
            app.Maxiteracji.Limits = [0 Inf];
            app.Maxiteracji.Value = 200;
            app.WykresAustralia = uiaxes(app.AustraliaTab);
            title(app.WykresAustralia, 'Wizualizacja')
            xlabel(app.WykresAustralia, '')
            ylabel(app.WykresAustralia, '')
            app.WykresAustralia.XLim = [0 200];
            app.WykresAustralia.YLim = [0 200];
            app.WykresAustralia.XTick = [];
            app.WykresAustralia.YTick = [];
            app.TableAustralia = uitable(app.AustraliaTab);
            app.TableAustralia.ColumnName = {'Iteracja'; 'Zakażenia'; 'Wyleczenia'; 'Liczba śmierci'; 'Liczba testów'};
            app.TableAustralia.RowName = {};
            app.Text1_4 = uilabel(app.AustraliaTab);
            app.Text1_4.Text = {'Dostępne stany:'; 'Q1:'; '    1 - healthy'; '    2 - recovered'; '    3 - sick'; '    4 - in_quarantine'; '    5 - infected_and_sick'; '    6 - infected'; '    7 - in_hospital'; '    8 - dead'; ''};
            app.Text1_5 = uilabel(app.AustraliaTab);
            app.Text1_5.Text = {'Q2:'; '    no_security_measures'; '    infecting'; '    self-protecting'; '    protecting_others'; '    organizing_protection'};
            app.Text1_6 = uilabel(app.AustraliaTab);
            app.Text1_6.Text = {'Symulacja ma na celu oszacowanie rozwoju epidemii wirusa Covid-19 w Australii. '; 'Każda komórka znajduje się w jednym ze stanów Q1 i w jednym ze stanów Q2. '; 'Jej zarażenie zależy od stanu sąsiadów oraz od jej stanu (czy jest osłabiona, czy'; ' jest w kwarantannie, itp.).Komórka zarażona może być w stanie infected (nieświadome'; 'zarażenie), infected_and_sick (zakażenie z objawami), in_quarantine, in_hospital.'; 'Komórka może sama z siebie wyzdrowieć albo wyjść z kwarantanny albo szpitala'; ' (wszystko to po odpowiednim czasie).'; 'Do kiedy było to możliwe, otrzymane wyniki porównano ze stanem rzeczywistym.'};
            app.Text1_7 = uilabel(app.AustraliaTab);
            app.Text1_7.Text = 'Dane rzeczywiste:';
            app.SEIRTab = uitab(app.TabGroup);
            app.SEIRTab.Title = 'Równania różniczkowe';
            app.Text1_8 = uilabel(app.SEIRTab);
            app.Text1_8.Text = {'Dla Austrii wykonano model symulujący przebieg epidemii inspirowany modelem SEIR. '; 'Wynik jego działania zwizualizowano wykresami aktywnych przypadków koronawirusa'; 'oraz śmierci nim spowodowanych. W tabeli przedstawiono wynik symulacji dla '; 'ostatniego dnia.'; 'Sterowaniem jest liczba testów. Domyślny współczynnik liczby testów jest tym '; 'odpowiadającym rzeczywistym danym.'; 'Czas symulacji również podlega ustaleniu, jest to liczba dni licząc od początku epidemii.'; 'Można również zmieniać resztę współczynników opisanych poniżej.'; 'Symulacja rozpocznie się po naciśnięciu przycisku START.'};
            app.aEditFieldLabel = uilabel(app.SEIRTab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.SEIRTab, 'numeric');
            app.aEditField.Value = 0.75;
            app.bEditFieldLabel = uilabel(app.SEIRTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.SEIRTab, 'numeric');
            app.bEditField.Value = 0.18;
            app.cEditFieldLabel = uilabel(app.SEIRTab);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.SEIRTab, 'numeric');
            app.cEditField.Value = 0.001;
            app.dEditFieldLabel = uilabel(app.SEIRTab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.SEIRTab, 'numeric');
            app.dEditField.Value = 0.001;
            app.eEditFieldLabel = uilabel(app.SEIRTab);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.SEIRTab, 'numeric');
            app.eEditField.Value = 0.2;
            app.fEditFieldLabel = uilabel(app.SEIRTab);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.SEIRTab, 'numeric');
            app.fEditField.Value = 0.03;
            app.gEditFieldLabel = uilabel(app.SEIRTab);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.SEIRTab, 'numeric');
            app.gEditField.Value = 0.909;
            app.hEditFieldLabel = uilabel(app.SEIRTab);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.SEIRTab, 'numeric');
            app.hEditField.Value = 0.023;
            app.iEditFieldLabel = uilabel(app.SEIRTab);
            app.iEditFieldLabel.Text = 'i';
            app.iEditField = uieditfield(app.SEIRTab, 'numeric');
            app.iEditField.Value = 0.001;
            app.jEditFieldLabel = uilabel(app.SEIRTab);
            app.jEditFieldLabel.Text = 'j';
            app.jEditField = uieditfield(app.SEIRTab, 'numeric');
            app.jEditField.Value = 0.85;
            app.kEditFieldLabel = uilabel(app.SEIRTab);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.SEIRTab, 'numeric');
            app.kEditField.Value = 0.599;
            app.lEditFieldLabel = uilabel(app.SEIRTab);
            app.lEditFieldLabel.Text = 'l';
            app.lEditField = uieditfield(app.SEIRTab, 'numeric');
            app.lEditField.Value = 0.001;
            app.mEditFieldLabel = uilabel(app.SEIRTab);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.SEIRTab, 'numeric');
            app.mEditField.Value = 0.996;
            app.nEditFieldLabel = uilabel(app.SEIRTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.SEIRTab, 'numeric');
            app.nEditField.Value = 0.003;
            app.STARTButton_SEIR = uibutton(app.SEIRTab, 'push');
            app.STARTButton_SEIR.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_SEIRPushed, true);
            app.STARTButton_SEIR.Text = 'START';
            app.DeadPlot = uiaxes(app.SEIRTab);
            title(app.DeadPlot, 'Dead')
            xlabel(app.DeadPlot, '')
            ylabel(app.DeadPlot, '')
            app.Text1_9 = uilabel(app.SEIRTab);
            app.Text1_9.Text = {'  a: scalar [1x1]: healthy -> exposed rate'; '  b: scalar [1x1]: exposed -> infected = infection rate'; '  c: scalar [1x1]: infected -> sick '; '  d: scalar [1x1]: infected -> in_quarantine fitted  rate at which people enter in quarantine'; '  e: scalar [1x1]: infected -> recovered = recovery rate (asymptomatic)'; '  f: scalar [1x1]: sick -> recovered'; '  g: scalar [1x1]: sick -> in hospital'; '  h: scalar [1x1]: sick -> in quarantine'; '  i: scalar [1x1]: sick -> dead'; '  j: scalar [1x1]: in quarantine -> recovered'; '  k: scalar [1x1]: in quarantine -> in hospital'; '  l: scalar [1x1]: in quarantine -> dead'; '  m: scalar [1x1]: in hospital -> recovered'; '  n: scalar [1x1]: in hospital -> dead'};
            app.CasesPlot = uiaxes(app.SEIRTab);
            title(app.CasesPlot, 'Active cases')
            xlabel(app.CasesPlot, '')
            ylabel(app.CasesPlot, '')
            app.SterowanieuEditFieldLabel = uilabel(app.SEIRTab);
            app.SterowanieuEditFieldLabel.Text = 'Sterowanie (u):';
            app.uEditField = uieditfield(app.SEIRTab, 'numeric');
            app.uEditField.Value = 0.005;
            app.DugosymulacjiEditFieldLabel = uilabel(app.SEIRTab);
            app.DugosymulacjiEditFieldLabel.Text = 'Długość symulacji:';
            app.DugosymulacjiEditField = uieditfield(app.SEIRTab, 'numeric');
            app.DugosymulacjiEditField.Value = 162;
            app.SEIRTable = uitable(app.SEIRTab);
            app.SEIRTable.ColumnName = {'Dzień'; 'Active Cases'; 'Dead'};
            app.SEIRTable.RowName = {};
            app.SymulacjaepidemiidlaAustriiLabel_2 = uilabel(app.SEIRTab);
            app.SymulacjaepidemiidlaAustriiLabel_2.Text = 'Symulacja rozwoju epidemii COVID-19 w Austrii z pomocą równań różniczkowych';
            % Show the figure after all components are created
            app.SymulacjaEpidemii.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplikacja_glowna

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SymulacjaEpidemii)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SymulacjaEpidemii)
        end
    end
end